classdef SEVVAL1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectCOMDropDown       matlab.ui.control.DropDown
        SelectCOMDropDownLabel  matlab.ui.control.Label
        Switch                  matlab.ui.control.Switch
        SwitchLabel             matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        LampLabel               matlab.ui.control.Label
        graph4                  matlab.ui.control.UIAxes
        graph3                  matlab.ui.control.UIAxes
        graph2                  matlab.ui.control.UIAxes
        graph1                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         a;
        b;
        x;
        numSamples;
        Fs;
        amplificationFactor;
        e;
        delay = 10;
        a_temp;
    end
     methods (Access = private)
    
       function plotGraph(app)
        % Start value
        app.x = zeros(1, 100);
        filteredXvalues = zeros(1,100);
        
        % samples
        app.numSamples = 1000;
        
        % Fs(Hz)
        app.Fs = 400;
        
        % Amplifikasyon faktörü
        app.amplificationFactor = 10;

           
        % Bandpass filter parameters
        filterOrder = 2;
        lowCutoff = 198;  %  low cutoff frequency
        highCutoff = 20;  %  high cutoff frequency

        % Normalize cutoff frequencies
    nyquistFreq = app.Fs / 2;
    lowCutoffNorm = lowCutoff / nyquistFreq;
    highCutoffNorm = highCutoff / nyquistFreq;

% Design the Butterworth bandpass filter
[c, d] = butter(filterOrder, [highCutoffNorm,lowCutoffNorm], 'bandpass');

        
        % swcih butonun değeri On olduğunda data okuyup grafik çizdirilir aksi taktirde kod hiçbir şey yapmaz (while çalışmaz)
    
        while strcmp(app.Switch.Value, 'On')
            % Analog pin A0'den voltaj oku
            app.b = readVoltage(app.a, 'A0');
            disp('app.b: ');
            disp(app.b);
            
            % Amplifikasyon uygula
            b_amplified = app.b * app.amplificationFactor;
            
            % Okunan değeri x'e ekle
            app.x = [app.x, b_amplified];
           
            % Grafikte sadece son 100 örnek tutulur
            if length(app.x) > 100
                app.x = app.x(end-99:end);
            end
            
            % İlk grafik çizimi
            
            plot(app.graph1, 1:length(app.x), app.x);
     
            
            % Sinyalin ortalamasını çiz
            %plot(1:length(app.x), mean(app.x)*ones(1,length(app.x)), 'r--', 'LineWidth', 2);
 
            
            % Eksen isimleri ve başlık
            xlabel(app.graph1,'Time');
            ylabel(app.graph1,'(V)');
            title(app.graph1,'Raw Graph');
            
            % Sinyalin maksimum ve minimum değerlerine dayalı olarak y eksenini sınırla
            

            % FFT parametreleri
            N = length(app.x);
            f = app.Fs*(0:(N/2))/N;
            
            % FFT uygula
            Y = fft(app.x);
            P2 = abs(Y/N);
            P1 = P2(1:N/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            % Güç spektrumu grafiği çizmek
          
            plot(app.graph2, f, P1);
            title(app.graph2,'Power Spectrum');
            xlabel(app.graph2,'Frequency (Hz)');
            ylabel(app.graph2,'Power');

            % Applying bandpass filter
            b_filtered = filter(c, d, app.x);
            % Okunan değeri x'e ekler
            minLength = min(length(app.x), length(b_filtered));
            app.e = [app.x(end-minLength+1:end), b_filtered(1:minLength)];
            
            % Grafikte sadece son 100 örneği tut
            % Amplifikasyon uygula
            b_amplified2 = b_filtered * app.amplificationFactor;
            filteredXvalues = [filteredXvalues, b_filtered];
            if length(filteredXvalues) > 100
                filteredXvalues = filteredXvalues(end-99:end);
            end

                % Grafik çizimi
                disp('filteredXvalues');
                disp(filteredXvalues);
                
                plot(app.graph3, 1:length(filteredXvalues), filteredXvalues);


            % FFT parametreleri
            N = length(app.e);
            f = app.Fs*(0:(N/2))/N;
            
            % FFT uygula
            Y = fft(app.e);
            P2 = abs(Y/N);
            P1 = P2(1:N/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            % Güç spektrumu grafiği çiz
          
            plot(app.graph4, f, P1);
            title(app.graph4,'Power Spectrum');
            xlabel(app.graph4,'Frequency (Hz)');
            ylabel(app.graph4,'Power');
            
            % Çizimi güncelleme kısmı
            drawnow;
            
            % Bekleme süresi
            pause(0.01);
        end
    end
   end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
             if strcmp(value, 'On')
                % Swcih buton açık ise
                app.Lamp.Color = [0,1,0];
                app.plotGraph();
            else 
                %Eğer swcih buton kapalı ise
                app.Lamp.Color = [1,0,0];
            end
        end

        % Value changed function: SelectCOMDropDown
        function SelectCOMDropDownValueChanged(app, event)
            value = app.SelectCOMDropDown.Value;
            try
                  app.a = arduino(value, 'UNO');
                  % arduinoya bağlanınca ledin rengini mavi yanar
               
                  app.Lamp.Color=[0.07,0.62,1];
            catch
                  disp('problem on the arduino');
                  % arduinoya bağlanınca ledin rengini kırmızı yanar
               
                  app.Lamp.Color=[1,0,0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graph1 = uiaxes(app.UIFigure);
            title(app.graph1, 'Raw-Signal')
            xlabel(app.graph1, 'X')
            ylabel(app.graph1, 'V')
            zlabel(app.graph1, 'Z')
            app.graph2 = uiaxes(app.UIFigure);
            title(app.graph2, 'FFT of Raw-Signal')
            xlabel(app.graph2, 'X')
            ylabel(app.graph2, 'hZ')
            zlabel(app.graph2, 'Z')
            app.graph3 = uiaxes(app.UIFigure);
            title(app.graph3, 'Filtered Signal')
            xlabel(app.graph3, 'X')
            ylabel(app.graph3, 'V')
            zlabel(app.graph3, 'Z')
            app.graph4 = uiaxes(app.UIFigure);
            title(app.graph4, 'FFT of Filtered signal')
            xlabel(app.graph4, 'X')
            ylabel(app.graph4, 'hZ')
            zlabel(app.graph4, 'Z')
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.SelectCOMDropDownLabel = uilabel(app.UIFigure);
            app.SelectCOMDropDownLabel.Text = 'Select COM';
            app.SelectCOMDropDown = uidropdown(app.UIFigure);
            app.SelectCOMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5'};
            app.SelectCOMDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCOMDropDownValueChanged, true);
            app.SelectCOMDropDown.Value = 'COM1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEVVAL1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end